classdef fuzzyPrediksiHargaBawang < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        PerKGLabel                matlab.ui.control.Label
        SatuanProduksidanHargaadalahper1000TonLabel  matlab.ui.control.Label
        KeteranganLabel           matlab.ui.control.Label
        PesanLabel                matlab.ui.control.Label
        HargaEFAngka              matlab.ui.control.EditField
        HargaLabel                matlab.ui.control.Label
        PrediksiHargaLabel        matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        PrediksiButton            matlab.ui.control.Button
        CuacaEF                   matlab.ui.control.NumericEditField
        CuacaEditFieldLabel       matlab.ui.control.Label
        KebutuhanEF               matlab.ui.control.NumericEditField
        KebutuhanEditFieldLabel   matlab.ui.control.Label
        ProduksiEF                matlab.ui.control.NumericEditField
        ProduksiEditFieldLabel    matlab.ui.control.Label
        ParameterLabel            matlab.ui.control.Label
        PrediksiHargaBawangLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PrediksiButton
        function PrediksiButtonPushed(app, event)
            % Ditambahkan
            fis = readfis('fuzzyPrediksiHargaBawang');
            produksi = app.ProduksiEF.Value;
            kebutuhan = app.KebutuhanEF.Value;
            cuaca = app.CuacaEF.Value;
            harga = evalfis(fis, [produksi kebutuhan cuaca]);

            if sprintf('%.0f', harga) == "50246"
                app.HargaEFAngka.Value = sprintf('± %.0f', harga);
            elseif sprintf('%.0f', harga) == "12637"
                app.HargaEFAngka.Value = sprintf('± %.0f', harga);
            else
                app.HargaEFAngka.Value = sprintf('%.0f', harga);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Ditambahakan
            app.KebutuhanEF.Value = 1;
            app.ProduksiEF.Value = 1;
            app.CuacaEF.Value = 0;
            app.HargaEFAngka.Value = '0';
            app.PesanLabel.Text = "PESAN: Kosong";
        end

        % Value changed function: ProduksiEF
        function ProduksiEFValueChanged(app, event)
            value = app.ProduksiEF.Value;
            if value > 170
                app.ProduksiEF.Value = 170;
                app.PesanLabel.Text = "PESAN: Produksi tidak boleh lebih dari 170!";
            elseif value < 1
                app.ProduksiEF.Value = 1;
                app.PesanLabel.Text = "PESAN: Produksi tidak boleh kurang dari 1!";
            end
        end

        % Value changed function: KebutuhanEF
        function KebutuhanEFValueChanged(app, event)
            value = app.KebutuhanEF.Value;
            if value > 117
                app.KebutuhanEF.Value = 117;
                app.PesanLabel.Text = "PESAN: Kebutuhan tidak boleh lebih dari 117!";
            elseif value < 1
                app.KebutuhanEF.Value = 1;
                app.PesanLabel.Text = "PESAN: Kebutuhan tidak boleh kurang dari 1!";
            end
        end

        % Value changed function: CuacaEF
        function CuacaEFValueChanged(app, event)
            value = app.CuacaEF.Value;
            if value > 10
                app.CuacaEF.Value = 10;
                app.PesanLabel.Text = "PESAN: Cuaca tidak boleh lebih dari 10!";
            elseif value < 0
                app.CuacaEF.Value = 0;
                app.PesanLabel.Text = "PESAN: Cuaca tidak boleh kurang dari 0!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PrediksiHargaBawangLabel = uilabel(app.GridLayout);
            app.PrediksiHargaBawangLabel.Tooltip = {'Obi Agustina'; '201011400884'; '07TPLP013'};
            app.PrediksiHargaBawangLabel.Text = 'Prediksi Harga Bawang';
            app.ParameterLabel = uilabel(app.GridLayout);
            app.ParameterLabel.Text = 'Parameter';
            app.ProduksiEditFieldLabel = uilabel(app.GridLayout);
            app.ProduksiEditFieldLabel.Text = 'Produksi';
            app.ProduksiEF = uieditfield(app.GridLayout, 'numeric');
            app.ProduksiEF.ValueChangedFcn = createCallbackFcn(app, @ProduksiEFValueChanged, true);
            app.ProduksiEF.Tooltip = {'Produksi : [ 1-170 ]'};
            app.ProduksiEF.Value = 1;
            app.KebutuhanEditFieldLabel = uilabel(app.GridLayout);
            app.KebutuhanEditFieldLabel.Text = 'Kebutuhan';
            app.KebutuhanEF = uieditfield(app.GridLayout, 'numeric');
            app.KebutuhanEF.ValueChangedFcn = createCallbackFcn(app, @KebutuhanEFValueChanged, true);
            app.KebutuhanEF.Tooltip = {'Kebutuhan Konsumsi : [ 1-117 ]'};
            app.KebutuhanEF.Value = 1;
            app.CuacaEditFieldLabel = uilabel(app.GridLayout);
            app.CuacaEditFieldLabel.Text = 'Cuaca';
            app.CuacaEF = uieditfield(app.GridLayout, 'numeric');
            app.CuacaEF.ValueChangedFcn = createCallbackFcn(app, @CuacaEFValueChanged, true);
            app.CuacaEF.Tooltip = {'Kualitas Cuaca : [ 0-10 ]'};
            app.PrediksiButton = uibutton(app.GridLayout, 'push');
            app.PrediksiButton.ButtonPushedFcn = createCallbackFcn(app, @PrediksiButtonPushed, true);
            app.PrediksiButton.Tag = 'tombolHitung';
            app.PrediksiButton.Text = 'Prediksi';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PrediksiHargaLabel = uilabel(app.GridLayout);
            app.PrediksiHargaLabel.Text = 'Prediksi Harga';
            app.HargaLabel = uilabel(app.GridLayout);
            app.HargaLabel.Text = 'Harga (Rp.)';
            app.HargaEFAngka = uieditfield(app.GridLayout, 'text');
            app.HargaEFAngka.Editable = 'off';
            app.HargaEFAngka.Tooltip = {'Turun : [ 5000-25000 ]'; 'Stabil : [ 15000-50000 ]'; 'Naik : [ 33000-60000 ]'};
            app.HargaEFAngka.Placeholder = '0';
            app.PesanLabel = uilabel(app.GridLayout);
            app.PesanLabel.Text = 'PESAN: Kosong';
            app.KeteranganLabel = uilabel(app.GridLayout);
            app.KeteranganLabel.Text = 'Keterangan:';
            app.SatuanProduksidanHargaadalahper1000TonLabel = uilabel(app.GridLayout);
            app.SatuanProduksidanHargaadalahper1000TonLabel.Text = 'Satuan Produksi dan Harga adalah per 1000 Ton';
            app.PerKGLabel = uilabel(app.GridLayout);
            app.PerKGLabel.Text = '/kg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fuzzyPrediksiHargaBawang

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end